classdef FourierApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ShowSearchVectorScanningSwitchLabel  matlab.ui.control.Label
        ShowSearchVectorScanningSwitch  matlab.ui.control.Switch
        SingleImageorMultipleImagesButtonGroup  matlab.ui.container.ButtonGroup
        SingleImageloadanimageButton    matlab.ui.control.RadioButton
        ImageSeriesloadadirectoryButton  matlab.ui.control.RadioButton
        DFFTMeshAngleMeasurementLabel   matlab.ui.control.Label
        FlareAngleEditFieldLabel        matlab.ui.control.Label
        FlareAngleEditField             matlab.ui.control.NumericEditField
        AngleStepEditFieldLabel         matlab.ui.control.Label
        AngleStepEditField              matlab.ui.control.NumericEditField
        ImageorFolderAddressEditField_2Label  matlab.ui.control.Label
        ImageorFolderAddressEditField_2  matlab.ui.control.EditField
        RunButton                       matlab.ui.control.Button
        ScanAnimationSpeedSliderLabel   matlab.ui.control.Label
        ScanAnimationSpeedSlider        matlab.ui.control.Slider
        PlotDataFileImageSeriesOnlyLabel  matlab.ui.control.Label
        PlotDataFileImageSeriesOnlyEditField  matlab.ui.control.EditField
        RightPanel                      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        RunMode;
        Input;
        flareAngle;
        stepSize;
        showScan;
        scanSpeed;
        braidAngle;
        imgNum;
        pos;
        phi;
        rad;
    end
    
    methods (Access = private)
        
        function doFourier1(app)
            % doFourier1 performs a Fourier Transform followed by a search
            % vector sweep analysis of the mean pixel intensity in
            % directions 0-180 degrees. for single image mode, a mean pixel
            % intensity distribution plot is shown. for multi image mode,
            % the braid angle vs IMPORTED DATA is shown.
            
            I = imread(app.Input);
            
            app.flareAngle = app.FlareAngleEditField.Value;
            app.stepSize = app.AngleStepEditField.Value;
            
            if app.ShowSearchVectorScanningSwitch.Value == string('Off')
                app.showScan = 0;
            elseif app.ShowSearchVectorScanningSwitch.Value == string('On')
                app.showScan = 1;
            end
            
            if app.imgNum == 1
                h = figure(1);
                imshow(I)
                title('Click and drag to create square section.')
                xlabel('Double-click when section is chosen')
                sqSect = drawrectangle(gca,'AspectRatio',1,'FixedAspectRatio',true);
                [app.pos] = customWait(app, sqSect);
                close(h)
                app.pos = round(app.pos,0);
            end
            I = I(app.pos(2):app.pos(2)+app.pos(4),app.pos(1):app.pos(1)+app.pos(3),:);
            imshow(I,'parent',app.UIAxes2);
            drawnow
            I = rgb2gray(I); %must be in grayscale to perform fft2
            Ab = abs(fftshift(fft2(I)));
            [r,c] = size(Ab);
            clims = [500 15000];
            imagesc(Ab,'parent',app.UIAxes3)
            if app.RunMode == 2
                drawnow
                pause(0.4)
            end
            
            ax = app.UIAxes3;
            ax.CLim = clims;
            
            %find center by finding max value of Ab. Center is located at (cx,cy)
            [m1,~] = max(Ab);
            [~,cy] = max(m1);
            [m2,~] = max(Ab,[],2);
            [~,cx] = max(m2);
            %now figure out max R value. R will be used as a search vector, by which
            %mean pixel intensity in each direction will be found. The vector will
            %be sweeped about in a circle, while the image (fourier spectrum) may be
            %rectangular, so the max radius is determine from the smaller dimension of
            %the rectangle.
            if cy > cx
                maxR = r/2-1;
            else
                maxR = c/2-1;
            end
            
            %now the angle at which each pixel is located relative to the +x axis will
            %be found, so that a loop can be ran that adds pixels intensity in each
            %angle.
            
            %now make a grid of the angles which correspond to each cell. This is
            %measured about the center of the image, (cx2,cy2), from the postive x axis
            
            if app.imgNum == 1
                for ii = r:-1:1
                    for jj = c:-1:1
                        app.phi(ii,jj) = -atan2(ii-cx,jj-cy)*180/pi;
                        app.rad(ii,jj) = sqrt((ii-cx)^2 + (jj-cy)^2);
                    end
                end
            end
            
            app.phi(app.phi == -180) = 180;
            inRange = app.rad <= maxR & r >= floor(.05*maxR); % searches through a circular area, but leaves out pixels close to center
            angles = 0:app.stepSize:180;
            iterations = length(angles);
            meanPixIntensity = ones(iterations,1);
            for a = 1:iterations
                cellVals = abs(app.phi-angles(a)) <= 0.5*app.flareAngle & inRange; %find the indices of cells that correspond to each angle's fan shaped section
                pixels = Ab(cellVals); %index the pixels at the angle
                intensity = sum(Ab(cellVals)); %find the total intensity of cells within the region of this angle
                meanPixIntensity(a) = intensity / length(pixels);
                if app.showScan == 1
                    if rem(a,app.scanSpeed) == 0
                        Ab1 = Ab;
                        Ab1(cellVals) = 1000000; %arbitrary high value to illustrate the fan shaped search vector during animation
                        clims = [50 80000]; %if fourier transform responses aren't showing up much, lower the second clims value
                        imagesc(Ab1,'parent',app.UIAxes3)
                        ax = app.UIAxes3;
                        ax.CLim = clims;
                        drawnow; pause(0.07);
                    end
                end
            end
            
            % image has some non-physical response on the fourier transform
            % that show up as horizontal and vertical lines. these can
            % impede peak mean pixel intensity directions by showing up as
            % high peaks, so they must be set to 0.
            meanPixIntensity(1:floor(iterations*0.03)) = 0; %cushion of 0.03*iterations = about 5.5 degrees
            meanPixIntensity(end-floor(iterations*0.03):end) = 0;
            meanPixIntensity(floor(iterations/2)-floor(iterations*0.015):floor(iterations/2)+floor(iterations*0.015)) = 0;
            
            if app.RunMode == 1
                plot(app.UIAxes,angles,meanPixIntensity,'-k');
                ax = app.UIAxes;
                ax.XLabel.String = 'Angle (degrees)';
                ax.YLabel.String = 'Mean Pixel Intensity';
                [max1,maxIndex1] = max(meanPixIntensity(1:floor(a/2))); %first quadrant
                [max2,maxIndex2] = max(meanPixIntensity(floor(a/2):end)); %second quadrant
                maxIndex2 = maxIndex2+(floor(a/2))-1; % correction of -1 for the offset
                maxAngle1 = angles(maxIndex1);
                maxAngle2 = angles(maxIndex2);
                app.braidAngle = 1/2*(maxAngle2 - maxAngle1);
                set(get(app.UIAxes, 'title'), 'string','Pixel Intensity Spectrum')
                text(ax,15,1.01*max(max1,max2),strcat('Braid Angle =  ',num2str(app.braidAngle,5)))
            elseif app.RunMode == 2
                [~,maxIndex1] = max(meanPixIntensity(1:floor(a/2))); %first quadrant
                [~,maxIndex2] = max(meanPixIntensity(floor(a/2):end)); %second quadrant
                maxIndex2 = maxIndex2+(floor(a/2))-1; % correction of -1 for the offset
                maxAngle1 = angles(maxIndex1);
                maxAngle2 = angles(maxIndex2);
                app.braidAngle = 1/2*(maxAngle2 - maxAngle1);
            end
            
        end
        
        function [pos] = customWait(~,hROI)
            % Listen for mouse clicks on the ROI
            l = addlistener(hROI,'ROIClicked',@clickCallback);
            
            % Block program execution
            uiwait;
            
            % Remove listener
            delete(l);
            
            % Return the current position
            pos = hROI.Position;
            
            function clickCallback(~,evt)
                
                if strcmp(evt.SelectionType,'double')
                    uiresume;
                end
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {524, 524};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {275, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.braidAngle = [];
            selectedButton = app.SingleImageorMultipleImagesButtonGroup.SelectedObject;
            if selectedButton == app.SingleImageloadanimageButton
                app.RunMode = 1; %single image
            else
                app.RunMode = 2; %multi image
            end
            app.Input = app.ImageorFolderAddressEditField_2.Value;
            if app.Input(1) == '"'
                app.Input(1) = '';
            end
            if app.Input(end) == '"'
                app.Input(end) = '';
            end
            if app.RunMode == 1
                folderORfile = isfile(app.Input);
                if folderORfile == 0
                    error('Input must be file')
                end
            elseif app.RunMode == 2
                folderORfile = isfolder(app.Input);
                if folderORfile == 0
                    error('Input must be folder');
                end
            end
            
            app.scanSpeed = round(app.ScanAnimationSpeedSlider.Value+10,0);
            
            if app.RunMode == 1
                % single image mode returns an image of the section, the fourier transform,...
                % a distribution plot, and the mesh angle. scanning animation on the
                % fourier transform is optional
                app.imgNum = 1;
                doFourier1(app)
            elseif app.RunMode == 2
                % multi image mode returns (for each image in folder, ...
                % one at a time in order) an image of the section, the fourier transform,...
                % (scanning animation is optional), and a plot of braid
                % angle vs time.
                folder = app.Input;
                list = dir(folder);
                list = list(3:end); %first 2 folders are '.' and '..' which are not useful here
                numImgs = numel(list);
                braidAngleData = ones(numImgs,1);
                for n = 1:numImgs %images in folder
                    app.Input = strcat(folder,'\',list(n).name);
                    app.imgNum = n;
                    doFourier1(app)
                    braidAngleData(n) = app.braidAngle;
                end
                
                % now get file for plotting quasi-static test performance.
                %
                % must be formatted like the following example, using pressure:
                % Pressure (psi)
                % <psi of img1>
                % <psi of img2>
                % and so on, with a new value on each line (without percent
                % symbols). must have a value for each image. if image
                % series is 5 images taken during quasi-static test, the
                % txt file loaded here must be 6 lines: 1 line for label of
                % what the data is, and 5 lines of just numerical values.
                
                file = app.PlotDataFileImageSeriesOnlyEditField.Value;
                if file(1) == '"'
                    file(1) = '';
                end
                if file(end) == '"'
                    file(end) = '';
                end
                if isfile(file) == 0
                    error('Input must be file')
                end
                fid = fopen(file,'rt');
                if fid==-1
                    error(['Input file ',file,' not available']);
                end
                fid = fopen(file, 'rt' );
                xL = fgetl( fid );
                importPlotData = fscanf( fid, '%f' );
                [~] = fclose( fid );
                plot(app.UIAxes,importPlotData,braidAngleData);
                ax = app.UIAxes;
                ax.XLabel.String = xL;
                ax.YLabel.String = 'Braid Angle (deg)';
                set(get(app.UIAxes, 'title'), 'string',"Braid Angle (deg) vs" + " " + xL)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ShowSearchVectorScanningSwitchLabel = uilabel(app.LeftPanel);
            app.ShowSearchVectorScanningSwitchLabel.Text = 'Show Search Vector Scanning';
            app.ShowSearchVectorScanningSwitch = uiswitch(app.LeftPanel, 'slider');
            app.SingleImageorMultipleImagesButtonGroup = uibuttongroup(app.LeftPanel);
            app.SingleImageorMultipleImagesButtonGroup.Title = 'Single Image or Multiple Images';
            app.SingleImageloadanimageButton = uiradiobutton(app.SingleImageorMultipleImagesButtonGroup);
            app.SingleImageloadanimageButton.Text = 'Single Image (load an image)';
            app.SingleImageloadanimageButton.Value = true;
            app.ImageSeriesloadadirectoryButton = uiradiobutton(app.SingleImageorMultipleImagesButtonGroup);
            app.ImageSeriesloadadirectoryButton.Text = 'Image Series (load a directory)';
            app.DFFTMeshAngleMeasurementLabel = uilabel(app.LeftPanel);
            app.DFFTMeshAngleMeasurementLabel.Text = '2D FFT Mesh Angle Measurement';
            app.FlareAngleEditFieldLabel = uilabel(app.LeftPanel);
            app.FlareAngleEditFieldLabel.Text = 'Flare Angle';
            app.FlareAngleEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FlareAngleEditField.Value = 0.05;
            app.AngleStepEditFieldLabel = uilabel(app.LeftPanel);
            app.AngleStepEditFieldLabel.Text = 'Angle Step ';
            app.AngleStepEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AngleStepEditField.Value = 0.1;
            app.ImageorFolderAddressEditField_2Label = uilabel(app.LeftPanel);
            app.ImageorFolderAddressEditField_2Label.Text = {'Image or Folder'; 'Address:     '};
            app.ImageorFolderAddressEditField_2 = uieditfield(app.LeftPanel, 'text');
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ScanAnimationSpeedSliderLabel = uilabel(app.LeftPanel);
            app.ScanAnimationSpeedSliderLabel.Text = {'Scan'; 'Animation '; 'Speed'};
            app.ScanAnimationSpeedSlider = uislider(app.LeftPanel);
            app.ScanAnimationSpeedSlider.Limits = [0 120];
            app.ScanAnimationSpeedSlider.Value = 30;
            app.PlotDataFileImageSeriesOnlyLabel = uilabel(app.LeftPanel);
            app.PlotDataFileImageSeriesOnlyLabel.Text = {'Plot Data File:    '; '(Image Series Only)'};
            app.PlotDataFileImageSeriesOnlyEditField = uieditfield(app.LeftPanel, 'text');
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Image')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes2.XTick = [];
            app.UIAxes2.XTickLabel = '';
            app.UIAxes2.YTick = [];
            app.UIAxes2.YTickLabel = '';
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'Fourier Transform')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes3.XTick = [];
            app.UIAxes3.XTickLabel = '';
            app.UIAxes3.YTick = [];
            app.UIAxes3.YTickLabel = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FourierApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end